classdef ProyectoPID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        rightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        BasicoperationsTab              matlab.ui.container.Tab
        TabPunto2                       matlab.ui.container.TabGroup
        GrayscaleTab                    matlab.ui.container.Tab
        changeDepth                     matlab.ui.control.Button
        ImagenprocesadaLabel            matlab.ui.control.Label
        BitdepthEditField               matlab.ui.control.NumericEditField
        ProfundidaddebitsLabel          matlab.ui.control.Label
        depthAxis                       matlab.ui.control.UIAxes
        Ecualizacion                    matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        EqualizeimageButton             matlab.ui.control.Button
        originalHistAxis                matlab.ui.control.UIAxes
        processedHistAxis               matlab.ui.control.UIAxes
        processedImgAxis                matlab.ui.control.UIAxes
        originalImageAxis               matlab.ui.control.UIAxes
        Inversion                       matlab.ui.container.Tab
        binaryInvestment                matlab.ui.control.Button
        InvertedimageLabel              matlab.ui.control.Label
        InvertPhoto                     matlab.ui.control.Button
        Invert                          matlab.ui.control.Button
        ImagenprocesadainvertLabel      matlab.ui.control.Label
        invertAxis                      matlab.ui.control.UIAxes
        AdditionandsubtractionTab       matlab.ui.container.Tab
        Label_6                         matlab.ui.control.Label
        UploadimageButton               matlab.ui.control.Button
        Sus                             matlab.ui.control.Button
        Add                             matlab.ui.control.Button
        ImageProcAxis                   matlab.ui.control.UIAxes
        Image2Axis                      matlab.ui.control.UIAxes
        Image1Axis                      matlab.ui.control.UIAxes
        CollageTab                      matlab.ui.container.Tab
        TabPunto1                       matlab.ui.container.TabGroup
        CreatecollageTab                matlab.ui.container.Tab
        AddCurrentProcessedImageButton  matlab.ui.control.Button
        ResizeCanvasButton              matlab.ui.control.Button
        NewCanvasButton                 matlab.ui.control.Button
        elStr23EditField_2              matlab.ui.control.NumericEditField
        SizeEditField                   matlab.ui.control.NumericEditField
        SizeEditFieldLabel              matlab.ui.control.Label
        ExportCollageButton             matlab.ui.control.Button
        UndoButton_2                    matlab.ui.control.Button
        AddNewImageButton               matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        collageAxis                     matlab.ui.control.UIAxes
        RotationTab                     matlab.ui.container.Tab
        Insertmultiplesof45degreesLabel  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        anguloEditField                 matlab.ui.control.NumericEditField
        RotationangleLabel              matlab.ui.control.Label
        MirrorButton                    matlab.ui.control.Button
        RotateimageButton               matlab.ui.control.Button
        rotacionEspejoAxis              matlab.ui.control.UIAxes
        FiltersTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SmoothingfiltersTab             matlab.ui.container.Tab
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        LaplacianButton                 matlab.ui.control.Button
        LaplacianButton_2               matlab.ui.control.Button
        BoxtypeLabel                    matlab.ui.control.Label
        GaussianFilterButton            matlab.ui.control.Button
        StatisticalorderTab             matlab.ui.container.Tab
        ImageminfilterButton            matlab.ui.control.Button
        ImagemaxfilterButton            matlab.ui.control.Button
        ImagemedianfilterButton         matlab.ui.control.Button
        Label_11                        matlab.ui.control.Label
        FilterButton                    matlab.ui.control.Button
        NOrderfilterEditField           matlab.ui.control.NumericEditField
        NOrderfilterEditFieldLabel      matlab.ui.control.Label
        ImagefashionfilterButton        matlab.ui.control.Button
        ImageaverageFilterButton        matlab.ui.control.Button
        EdgedetectionTab                matlab.ui.container.Tab
        GetsthegradientfortheedgesAlthoughthemaskisdifferentLabel_2  matlab.ui.control.Label
        GetsthegradientfortheedgesAlthoughthemaskisdifferentLabel  matlab.ui.control.Label
        IthastheabilitytoshowedgepointsbutnottheirorientationLabel  matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        RobertsButton                   matlab.ui.control.Button
        PrewittButton                   matlab.ui.control.Button
        SobelButton                     matlab.ui.control.Button
        filtrosAxes                     matlab.ui.control.UIAxes
        MorphologicaloperationsTab      matlab.ui.container.Tab
        ErosionremovepixelsfromtheboundariesofobjectsLabel  matlab.ui.control.Label
        DilationaddspixelstotheboundariesofobjectsinanimageLabel  matlab.ui.control.Label
        StructuringelementLabel         matlab.ui.control.Label
        elStr12EditField                matlab.ui.control.NumericEditField
        elStr31EditField                matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        elStr33EditField                matlab.ui.control.NumericEditField
        elStr32EditField                matlab.ui.control.NumericEditField
        elStr21EditField                matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        elStr23EditField                matlab.ui.control.NumericEditField
        elStr22EditField                matlab.ui.control.NumericEditField
        elStr13EditField                matlab.ui.control.NumericEditField
        elStr11EditField                matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Erosion                         matlab.ui.control.Button
        Dilatacion                      matlab.ui.control.Button
        MorfoAxes                       matlab.ui.control.UIAxes
        SegmentationforNregionsTab      matlab.ui.container.Tab
        Label_13                        matlab.ui.control.Label
        NRegionsEditField               matlab.ui.control.NumericEditField
        NRegionsEditFieldLabel          matlab.ui.control.Label
        NRegionssegmentationSLICButton  matlab.ui.control.Button
        SegmentationimsegkmeansButton   matlab.ui.control.Button
        segAxes                         matlab.ui.control.UIAxes
        SaveTab                         matlab.ui.container.Tab
        ExportoriginalimageButton       matlab.ui.control.Button
        ExportprocessedimageButton      matlab.ui.control.Button
        ExportAxes                      matlab.ui.control.UIAxes
        leftPanel                       matlab.ui.container.Panel
        UndoButton                      matlab.ui.control.Button
        InsertyourimagehereLabel        matlab.ui.control.Label
        UploadimageButton_2             matlab.ui.control.Button
        ImageinformationLabel           matlab.ui.control.Label
        imageInfoTable                  matlab.ui.control.Table
        DigitalimageprocessingLabel     matlab.ui.control.Label
        imagenEntradaAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Proyecto % Instancia de clase prueba
        namePath
        originalImgMatrix
        originalMap
        eqImage
        grayScaleImage
        lastImage
        newImage
        newMap
        invImage
        originalHist
        eqHist
        binaryImage
        tableInfo
        rotation
        espejo

        segImg
        name

        AdSuImage
        DiErImage
        ElStr

        Proyecto2
        namePath2
        originalImgMatrix2
        originalMap2
        tableInfo2
        %imageInfoTable2
        grayScaleImage2

        filtroMedia
        filtroGauss
        valorN
        filtroN
        imageDouble
        filtroSobel
        filtroRoberts

        %----- Collage -----------------------------------------------------
        lienzo
        lastLienzo

        alto
        ancho

        Proyecto3
        namePath3
        originalImgMatrix3
        originalMap3
        tableInfo3
        %imageInfoTable3
        grayScaleImage3

        matImg
        
    end
    
    methods (Access = private)


        function showInAxis(app)

            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.depthAxis);
            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.originalImageAxis);
            imshow(app.originalImgMatrix ,app.newMap, 'Parent', app.rotacionEspejoAxis);
            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.filtrosAxes);
            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.ImageProcAxis);
            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.MorfoAxes);
            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.ExportAxes);
            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.segAxes);
            imshow(app.originalImgMatrix, app.newMap, 'Parent', app.invertAxis);


        end
        
        
        function updatePaneles(app)
            
            imshow(app.grayScaleImage, 'Parent',app.invertAxis);
            imshow(app.grayScaleImage, 'Parent', app.rotacionEspejoAxis);
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            imshow(app.grayScaleImage, 'Parent', app.ImageProcAxis);
            imshow(app.grayScaleImage, 'Parent', app.MorfoAxes);
            imshow(app.grayScaleImage, 'Parent', app.ExportAxes);
            imshow(app.grayScaleImage, 'Parent', app.segAxes);
            imshow(app.grayScaleImage, 'Parent', app.Image1Axis);
            imshow(app.grayScaleImage, 'Parent', app.originalImageAxis);


        end

        function genCollage(app)
            %I = [app.grayScaleImage,app.grayScaleImage2,app.grayScaleImage3,app.grayScaleImage4,app.grayScaleImage5,app.grayScaleImage6,app.grayScaleImage7,app.grayScaleImage8];
            %I = [app.grayScaleImage,imresize(app.grayScaleImage2,[app.tableInfo.(4) app.tableInfo.(3)]),imresize(app.grayScaleImage3,[app.tableInfo.(4) app.tableInfo.(3)]),imresize(app.grayScaleImage4,[app.tableInfo.(4) app.tableInfo.(3)]),imresize(app.grayScaleImage5,[app.tableInfo.(4) app.tableInfo.(3)]),imresize(app.grayScaleImage6,[app.tableInfo.(4) app.tableInfo.(3)]),imresize(app.grayScaleImage7,[app.tableInfo.(4) app.tableInfo.(3)]),imresize(app.grayScaleImage8,[app.tableInfo.(4) app.tableInfo.(3)])];
            %I = [app.grayScaleImage,imresize(app.grayScaleImage2,[app.tableInfo.(4) app.tableInfo.(3)])];
            I = app.matImg;
            montage(I, 'Parent',app.collageAxis);
            %imshow(A, 'Parent',app.collageAxis);


        end

        function agMat(app,im)
            app.matImg = [app.matImg,imresize(im,[app.tableInfo.(4) app.tableInfo.(3)])];


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadimageButton_2
        function UploadimageButton_2Pushed(app, event)
            app.Proyecto = Proyecto;

            app.namePath = app.Proyecto.nameAndPath;
            app.originalImgMatrix = app.Proyecto.imageMatrix;
            app.originalMap = app.Proyecto.imageMap;

            imshow(app.namePath, 'Parent', app.imagenEntradaAxes);
            imshow(app.originalImgMatrix, 'Parent', app.depthAxis);
            imshow(app.originalImgMatrix, 'Parent', app.Image1Axis);
            app.tableInfo = struct2table(app.Proyecto.imageInfo);
            app.imageInfoTable.Data = app.tableInfo;

            app.grayScaleImage = app.Proyecto.grayScaleImage;
            app.lastImage = app.grayScaleImage;

            app.name = app.Proyecto.name;
            %A = string(app.name);


            %app.alto = 500;
            %app.ancho = 500;
            %app.SizeEditField.Value = app.alto;
            %app.elStr23EditField_2.Value = app.ancho;

            app.matImg = [app.grayScaleImage];

            showInAxis(app);
        end

        % Value changed function: BitdepthEditField
        function BitdepthEditFieldValueChanged(app, event)
            value = app.BitdepthEditField.Value;
            app.newMap = app.Proyecto.changeDepth(value).imageMap;
            app.Proyecto.grayScaleImage = app.Proyecto.updateGrayScaleWithMap(app.newMap).grayScaleImage;
            app.grayScaleImage =  app.Proyecto.grayScaleImage;
        end

        % Button pushed function: changeDepth
        function changeDepthButtonPushed(app, event)
            imshow(app.grayScaleImage, 'Parent', app.depthAxis);
            showInAxis(app);
        end

        % Button pushed function: EqualizeimageButton
        function EqualizeimageButtonPushed(app, event)
            app.originalHist = app.Proyecto.getHistogram(app.grayScaleImage).imageHistogram;
            plot(app.originalHist, 'Parent',app.originalHistAxis);

            %----- Para deshacer el cambio borrar el app.grayScaleImage de
            %de las entradas
            app.Proyecto.equalizedImage = app.Proyecto.equalizeImage(app.grayScaleImage).equalizedImage;
            app.eqImage = app.Proyecto.equalizedImage;
            
            app.Proyecto.eqHistogram = app.Proyecto.getEqHist().eqHistogram;
            app.eqHist = app.Proyecto.eqHistogram;
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = app.eqImage;
            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;

            imshow(app.grayScaleImage, 'Parent',app.processedImgAxis);
            plot(app.eqHist, 'Parent',app.processedHistAxis);
            app.updatePaneles();
        end

        % Button pushed function: RotateimageButton
        function RotateimageButtonPushed(app, event)
            app.newImage = imrotate(app.grayScaleImage, app.rotation);
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = app.newImage;
            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;
            imshow(app.grayScaleImage ,app.newMap, 'Parent', app.rotacionEspejoAxis);
            app.updatePaneles();
        end

        % Value changed function: anguloEditField
        function anguloEditFieldValueChanged(app, event)
            value = app.anguloEditField.Value;
            if value > 360
                value = 360;
            elseif value == 45
                    value = 45;
            else
                app.rotation = value;
            end
            app.rotation = value;
            disp(app.rotation);
        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            %---------- Codigo original  
            %img2 = flipdim(app.originalImgMatrix,1);
            %img3 = flipdim(app.originalImgMatrix,2);
            %img4 = flipud(app.originalImgMatrix);
            %img5 = fliplr(img4);
            %app.espejo = [img2,img5;app.originalImgMatrix,img3];
%             app.espejo = [];
            %imshow(app.espejo, 'Parent', app.rotacionEspejoAxis);
            
            %--------- Codigo modificado
            img2 = flipdim(app.grayScaleImage,1);
            img3 = flipdim(app.grayScaleImage,2);
            img4 = flipud(app.grayScaleImage);
            img5 = fliplr(img4);
            app.espejo = [img2,img5;app.grayScaleImage,img3];
%             app.espejo = [];
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = app.espejo;
            imshow(app.grayScaleImage, 'Parent', app.rotacionEspejoAxis);
            app.updatePaneles();
        end

        % Button pushed function: ImageaverageFilterButton
        function ImageaverageFilterButtonPushed(app, event)
            app.filtroMedia = fspecial("average");
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = imfilter(app.grayScaleImage, app.filtroMedia);
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: GaussianFilterButton
        function GaussianFilterButtonPushed(app, event)
            app.filtroGauss = fspecial('gaussian');
            app.lastImage = app.grayScaleImage;


            %---------------- Codigo original
            %app.grayScaleImage = imfilter(app.grayScaleImage, app.filtroGauss);
            %---------------- Fin codigo original


            %---------------- Codigo modificado 8/6/23

            for c = 1:10
            app.grayScaleImage = imfilter(app.grayScaleImage, app.filtroGauss);
            end

            %---------------- Fin codigo modificado
            
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: ImagefashionfilterButton
        function ImagefashionfilterButtonPushed(app, event)
            
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = modefilt(app.grayScaleImage);
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Value changed function: NOrderfilterEditField
        function NOrderfilterEditFieldValueChanged(app, event)
            value = app.NOrderfilterEditField.Value;
            if value > 9
                value = 9;
            elseif value < 1
                    value = 1;
            else
                app.valorN = value;
            end
            %app.valorN = value;
            disp(app.valorN);
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            %app.imageDouble = im2double(app.grayScaleImage);
            %app.filtroN = ordfilt2(app.imageDouble, app.valorN, ones(3,3));

            %app.imageDouble = imfilter(app.imageDouble, app.filtroN);
            %app.lastImage = app.grayScaleImage;
            %app.grayScaleImage = im2int16(app.imageDouble);
            
            app.grayScaleImage = ordfilt2(app.grayScaleImage, app.valorN, ones(3,3));

            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: LaplacianButton_2
        function LaplacianButton_2Pushed(app, event)
            L4 = [0 -1 0; -1 5 -1; 0 -1 0];
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = imfilter(app.grayScaleImage, L4);
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            L8 = [-1 -1 -1; -1 9 -1; -1 -1 -1];
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = imfilter(app.grayScaleImage, L8);
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            Ph = [-1 0 1; -1 0 1; -1 0 1];
            Pv = [-1 -1 -1; 0 0 0; 1 1 1];
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = imfilter(app.grayScaleImage, Ph);
            app.grayScaleImage = imfilter(app.grayScaleImage, Pv);
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            app.filtroSobel = fspecial("sobel");
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = imfilter(app.grayScaleImage, app.filtroSobel);
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = edge(app.grayScaleImage, "roberts");
            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: Invert
        function InvertButtonPushed(app, event)
            % app.grayScaleImage = app.Proyecto.invertImage().invertedImage;

            % Revisar inversion binaria (convertir a binario, invertir,convertir a escala de grises de nuevo)

            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = imcomplement(app.grayScaleImage);
            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;
            imshow(app.grayScaleImage, 'Parent',app.invertAxis);
            app.updatePaneles();
        end

        % Button pushed function: InvertPhoto
        function InvertPhotoButtonPushed(app, event)
            %app.grayScaleImage = app.Proyecto.photoInvertImage().invertedImage;
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = 255 - (app.grayScaleImage);
            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;
            imshow(app.grayScaleImage, 'Parent',app.invertAxis);
            app.updatePaneles();
        end

        % Button pushed function: UploadimageButton
        function UploadimageButtonPushed(app, event)
            app.Proyecto2 = Proyecto;

            app.namePath2 = app.Proyecto2.nameAndPath;
            app.originalImgMatrix2 = app.Proyecto2.imageMatrix;
            app.originalMap2 = app.Proyecto2.imageMap;

            imshow(app.originalImgMatrix2, 'Parent', app.Image2Axis);
            %imshow(app.originalImgMatrix2, 'Parent', app.depthAxis);
            app.tableInfo2 = struct2table(app.Proyecto2.imageInfo);

            app.grayScaleImage2 = app.Proyecto2.grayScaleImage;

            %showInAxis(app);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)


            app.lastImage = app.grayScaleImage;
            %app.grayScaleImage = app.Proyecto.AddToImage(app.grayScaleImage2,app.tableInfo.(4),app.tableInfo.(3)).addImage;

            img2 =  imresize(app.grayScaleImage2,[app.tableInfo.(4) app.tableInfo.(3)]);
            app.grayScaleImage = app.grayScaleImage + img2;

            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;

            imshow(app.grayScaleImage, 'Parent', app.ImageProcAxis);
            app.updatePaneles();
        end

        % Button pushed function: Sus
        function SusButtonPushed(app, event)
            
            app.lastImage = app.grayScaleImage;
            %app.grayScaleImage =  app.Proyecto.SusToImage(app.grayScaleImage2,app.tableInfo.(4),app.tableInfo.(3)).susImage;

            img2 =  imresize(app.grayScaleImage2,[app.tableInfo.(4) app.tableInfo.(3)]);
            app.grayScaleImage = app.grayScaleImage - img2;

            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;

            imshow(app.grayScaleImage, 'Parent', app.ImageProcAxis);
            app.updatePaneles();
        end

        % Button pushed function: Dilatacion
        function DilatacionButtonPushed(app, event)
            app.ElStr = [app.elStr11EditField.Value   app.elStr12EditField.Value   app.elStr13EditField.Value ;  app.elStr21EditField.Value   app.elStr22EditField.Value   app.elStr23EditField.Value ;  app.elStr31EditField.Value   app.elStr32EditField.Value   app.elStr33EditField.Value ];
            
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;
            app.grayScaleImage = app.Proyecto.DilateImage(app.grayScaleImage,app.ElStr).dilImage;

            imshow(app.grayScaleImage, 'Parent', app.MorfoAxes);
            app.updatePaneles();
        end

        % Button pushed function: Erosion
        function ErosionButtonPushed(app, event)
            app.ElStr = [app.elStr11EditField.Value   app.elStr12EditField.Value   app.elStr13EditField.Value ;  app.elStr21EditField.Value   app.elStr22EditField.Value   app.elStr23EditField.Value ;  app.elStr31EditField.Value   app.elStr32EditField.Value   app.elStr33EditField.Value ];
            
            app.lastImage = app.grayScaleImage;
            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;
            app.grayScaleImage =  app.Proyecto.ErodeImage(app.grayScaleImage,app.ElStr).erImage;

            imshow(app.grayScaleImage, 'Parent', app.MorfoAxes);
            app.updatePaneles();
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            
            app.grayScaleImage = app.lastImage;
            app.updatePaneles();
        end

        % Button pushed function: ImagemaxfilterButton
        function ImagemaxfilterButtonPushed(app, event)
        
            app.grayScaleImage = ordfilt2(app.grayScaleImage, 9, ones(3,3));

            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: ImageminfilterButton
        function ImageminfilterButtonPushed(app, event)
            
            app.grayScaleImage = ordfilt2(app.grayScaleImage, 1, ones(3,3));

            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: ExportprocessedimageButton
        function ExportprocessedimageButtonPushed(app, event)
            %nom = app.FilenameEditField.Value;
            %I = cat(3,app.grayScaleImage,app.grayScaleImage,app.grayScaleImage);
            imsave(app.ExportAxes);
            %imwrite(I, ['Exports\',nom,'.tif'],'tif');
        end

        % Callback function
        function ExporttoPNGButtonPushed(app, event)
            nom = app.FilenameEditField.Value;
            I = cat(3,app.grayScaleImage,app.grayScaleImage,app.grayScaleImage);
            imwrite(I, ['Exports\',nom,'.png'],'png');

        end

        % Button pushed function: ExportoriginalimageButton
        function ExportoriginalimageButtonPushed(app, event)
            %nom = app.FilenameEditField.Value;
            %I = cat(3,app.grayScaleImage,app.grayScaleImage,app.grayScaleImage);
            %imwrite(I, ['Exports\',nom,'.jpeg'],'jpeg');
            imsave(app.originalImgMatrix);

        end

        % Button pushed function: SegmentationimsegkmeansButton
        function SegmentationimsegkmeansButtonPushed(app, event)
            
            %app.segmentacion = imsegkmeans(app.lastImage, 3);
            %app.grayScaleImage = labeloverlay(app.grayScaleImage, app.segmentacion);
            %imshow(app.grayScaleImage, 'Parent', app.segmentar_axis);

            imshow(labeloverlay(app.grayScaleImage,imsegkmeans(app.grayScaleImage,app.NRegionsEditField.Value)), 'Parent', app.segAxes);
        end

        % Button pushed function: NRegionssegmentationSLICButton
        function NRegionssegmentationSLICButtonPushed(app, event)
            [L,N] = superpixels(app.grayScaleImage,app.NRegionsEditField.Value);
            BW = boundarymask(L);
            imshow(imoverlay(app.grayScaleImage,BW,'red'), 'Parent', app.segAxes);
        end

        % Button pushed function: AddNewImageButton
        function AddNewImageButtonPushed(app, event)


            %r = randi([1 500],1,2);

            %A = imresize(app.grayScaleImage2, [r(1), r(2)]);
            %A = cast(A,'uint8');

            %h=randi([1 500-r(1)],1,1);

            %app.lienzo(h+1:h+r(1), h+1:h+r(2)) = A; 

            %imshow(app.lienzo,'Parent', app.collageAxis);


            app.lastLienzo = app.lienzo;

            app.Proyecto3 = Proyecto;

            app.namePath3 = app.Proyecto3.nameAndPath;
            app.originalImgMatrix3 = app.Proyecto3.imageMatrix;
            app.originalMap3 = app.Proyecto3.imageMap;

            %imshow(app.originalImgMatrix3, 'Parent', app.Image2Axis);
            %imshow(app.originalImgMatrix3, 'Parent', app.depthAxis);
            app.tableInfo3 = struct2table(app.Proyecto3.imageInfo);

            app.grayScaleImage3 = app.Proyecto3.grayScaleImage;

            rot = randi([1 360],1,1);

            A = imrotate(app.grayScaleImage3,rot);

            al = randi([1 app.alto],1,1);
            an = randi([1 app.ancho],1,1);

            A = imresize(A, [al, an]);
            %A = imresize(app.grayScaleImage3, [al, an]);
            A = cast(A,'uint8');

            hal=randi([1 app.alto-al],1,1);
            han=randi([1 app.ancho-an],1,1);

            app.lienzo(hal+1:hal+al, han+1:han+an) = A; 

            imshow(app.lienzo,'Parent', app.collageAxis);
            
            %showInAxis(app);
        end

        % Button pushed function: ImagemedianfilterButton
        function ImagemedianfilterButtonPushed(app, event)
            
            app.grayScaleImage = ordfilt2(app.grayScaleImage, 5, ones(3,3));

            imshow(app.grayScaleImage, 'Parent', app.filtrosAxes);
            app.updatePaneles();
        end

        % Button pushed function: binaryInvestment
        function binaryInvestmentButtonPushed(app, event)
            app.lastImage = app.grayScaleImage;
            
             binImage = imbinarize(app.grayScaleImage);

            app.grayScaleImage = imcomplement(binImage);

    app.grayScaleImage = uint8(255*app.grayScaleImage);

            app.grayScaleImage = app.Proyecto.Update(app.grayScaleImage).grayScaleImage;
            imshow(app.grayScaleImage, 'Parent',app.invertAxis);
            app.updatePaneles();
        end

        % Button pushed function: UndoButton_2
        function UndoButton_2Pushed(app, event)
            app.lienzo = app.lastLienzo;
            app.lastLienzo = app.lienzo;
            imshow(app.lienzo,'Parent', app.collageAxis);
        end

        % Button pushed function: ExportCollageButton
        function ExportCollageButtonPushed(app, event)
            
            imsave(app.collageAxis);
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            app.alto = app.SizeEditField.Value;

            
        end

        % Value changed function: elStr23EditField_2
        function elStr23EditField_2ValueChanged(app, event)
            app.ancho = app.elStr23EditField_2.Value;

            
        end

        % Button pushed function: NewCanvasButton
        function NewCanvasButtonPushed(app, event)
            app.lastLienzo = app.lienzo;
            app.lienzo = zeros(app.alto, app.ancho, 'uint8');
            imshow(app.lienzo, app.newMap, 'Parent', app.collageAxis);
        end

        % Button pushed function: ResizeCanvasButton
        function ResizeCanvasButtonPushed(app, event)
            app.lastLienzo = app.lienzo;
            app.lienzo = imresize(app.lienzo,[app.alto , app.ancho]);
            imshow(app.lienzo, app.newMap, 'Parent', app.collageAxis);
        end

        % Button pushed function: AddCurrentProcessedImageButton
        function AddCurrentProcessedImageButtonPushed(app, event)
            app.lastLienzo = app.lienzo;
            
            %app.lienzo(1:200, 1:200) = imresize(app.grayScaleImage, [200, 200]);
            %A = ones(200,200,'uint8');

            rot = randi([1 360],1,1);

            A = imrotate(app.grayScaleImage,rot);

            al = randi([1 app.alto],1,1);
            an = randi([1 app.ancho],1,1);

            A = imresize(A, [al, an]);
            %A = imresize(app.grayScaleImage, [al, an]);
            A = cast(A,'uint8');

            hal=randi([1 app.alto-al],1,1);
            han=randi([1 app.ancho-an],1,1);

            app.lienzo(hal+1:hal+al, han+1:han+an) = A; 

            imshow(app.lienzo,'Parent', app.collageAxis);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.leftPanel = uipanel(app.UIFigure);
            app.imagenEntradaAxes = uiaxes(app.leftPanel);
            app.imagenEntradaAxes.XTick = [];
            app.imagenEntradaAxes.YTick = [];
            app.DigitalimageprocessingLabel = uilabel(app.leftPanel);
            app.DigitalimageprocessingLabel.Text = 'Digital image processing';
            app.imageInfoTable = uitable(app.leftPanel);
            app.imageInfoTable.ColumnName = {'Format'; 'Depth'; 'Width'; 'Height'; 'Type'; 'FileSize'};
            app.imageInfoTable.RowName = {};
            app.ImageinformationLabel = uilabel(app.leftPanel);
            app.ImageinformationLabel.Text = 'Image information';
            app.UploadimageButton_2 = uibutton(app.leftPanel, 'push');
            app.UploadimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadimageButton_2Pushed, true);
            app.UploadimageButton_2.Text = 'Upload image';
            app.InsertyourimagehereLabel = uilabel(app.leftPanel);
            app.InsertyourimagehereLabel.Text = 'Insert your image here';
            app.UndoButton = uibutton(app.leftPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.rightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.rightPanel);
            app.BasicoperationsTab = uitab(app.TabGroup);
            app.BasicoperationsTab.Title = 'Basic operations';
            app.TabPunto2 = uitabgroup(app.BasicoperationsTab);
            app.GrayscaleTab = uitab(app.TabPunto2);
            app.GrayscaleTab.Title = 'Grayscale';
            app.depthAxis = uiaxes(app.GrayscaleTab);
            app.depthAxis.XTick = [];
            app.depthAxis.YTick = [];
            app.ProfundidaddebitsLabel = uilabel(app.GrayscaleTab);
            app.ProfundidaddebitsLabel.Text = 'Bit depth:';
            app.BitdepthEditField = uieditfield(app.GrayscaleTab, 'numeric');
            app.BitdepthEditField.ValueChangedFcn = createCallbackFcn(app, @BitdepthEditFieldValueChanged, true);
            app.BitdepthEditField.Value = 256;
            app.ImagenprocesadaLabel = uilabel(app.GrayscaleTab);
            app.ImagenprocesadaLabel.Text = 'To start we need to transform our original image to grayscale to be able to work and apply the desired filters';
            app.changeDepth = uibutton(app.GrayscaleTab, 'push');
            app.changeDepth.ButtonPushedFcn = createCallbackFcn(app, @changeDepthButtonPushed, true);
            app.changeDepth.Text = 'Process';
            app.Ecualizacion = uitab(app.TabPunto2);
            app.Ecualizacion.Title = 'Equalization';
            app.originalImageAxis = uiaxes(app.Ecualizacion);
            title(app.originalImageAxis, 'Original grayscale image')
            app.originalImageAxis.XTick = [];
            app.originalImageAxis.YTick = [];
            colormap(app.originalImageAxis, 'flag')
            app.processedImgAxis = uiaxes(app.Ecualizacion);
            title(app.processedImgAxis, 'Equalized image')
            app.processedImgAxis.XTick = [];
            app.processedImgAxis.YTick = [];
            app.processedHistAxis = uiaxes(app.Ecualizacion);
            title(app.processedHistAxis, 'Equalized Image Histogram')
            app.processedHistAxis.XTick = [];
            app.processedHistAxis.YTick = [];
            app.originalHistAxis = uiaxes(app.Ecualizacion);
            title(app.originalHistAxis, 'Original image histogram')
            app.originalHistAxis.XTick = [];
            app.originalHistAxis.YTick = [];
            app.EqualizeimageButton = uibutton(app.Ecualizacion, 'push');
            app.EqualizeimageButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizeimageButtonPushed, true);
            app.EqualizeimageButton.Text = 'Equalize image';
            app.Label_5 = uilabel(app.Ecualizacion);
            app.Label_5.Text = {'Automatically adjusts the lightness of the colors in the active layer so that the value channel''s histogram is as '; 'smooth as possible, that is, each possible lightness value appears at the same number of pixels as for the '; 'other values.'};
            app.Inversion = uitab(app.TabPunto2);
            app.Inversion.Title = 'Inversion';
            app.invertAxis = uiaxes(app.Inversion);
            app.invertAxis.XTick = [];
            app.invertAxis.YTick = [];
            app.ImagenprocesadainvertLabel = uilabel(app.Inversion);
            app.ImagenprocesadainvertLabel.Text = {'The determination of the opposite color in that color model is called inversion of a color. It is an '; 'operation that affects each pixel, regardless of its neighborhood. The reverse image can be thought '; 'of as "the reverse" or the "negative" of an image.'};
            app.Invert = uibutton(app.Inversion, 'push');
            app.Invert.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.Invert.Text = 'Inversion';
            app.InvertPhoto = uibutton(app.Inversion, 'push');
            app.InvertPhoto.ButtonPushedFcn = createCallbackFcn(app, @InvertPhotoButtonPushed, true);
            app.InvertPhoto.Text = 'Photo investment';
            app.InvertedimageLabel = uilabel(app.Inversion);
            app.InvertedimageLabel.Text = 'Inverted image';
            app.binaryInvestment = uibutton(app.Inversion, 'push');
            app.binaryInvestment.ButtonPushedFcn = createCallbackFcn(app, @binaryInvestmentButtonPushed, true);
            app.binaryInvestment.Text = 'Binary investment';
            app.AdditionandsubtractionTab = uitab(app.TabPunto2);
            app.AdditionandsubtractionTab.Title = 'Addition and subtraction';
            app.Image1Axis = uiaxes(app.AdditionandsubtractionTab);
            title(app.Image1Axis, 'Image 1')
            app.Image1Axis.XTick = [];
            app.Image1Axis.YTick = [];
            app.Image2Axis = uiaxes(app.AdditionandsubtractionTab);
            title(app.Image2Axis, 'Image 2')
            app.Image2Axis.XTick = [];
            app.Image2Axis.YTick = [];
            app.ImageProcAxis = uiaxes(app.AdditionandsubtractionTab);
            title(app.ImageProcAxis, 'Processed image')
            app.ImageProcAxis.XTick = [];
            app.ImageProcAxis.YTick = [];
            app.Add = uibutton(app.AdditionandsubtractionTab, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Addition';
            app.Sus = uibutton(app.AdditionandsubtractionTab, 'push');
            app.Sus.ButtonPushedFcn = createCallbackFcn(app, @SusButtonPushed, true);
            app.Sus.Text = 'Subtraction';
            app.UploadimageButton = uibutton(app.AdditionandsubtractionTab, 'push');
            app.UploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageButtonPushed, true);
            app.UploadimageButton.Text = 'Upload image';
            app.Label_6 = uilabel(app.AdditionandsubtractionTab);
            app.Label_6.Text = {'The operation adds and subtracts between images consists of merging the values  of 2 consists '; 'of merging the values of 2 input images'};
            app.CollageTab = uitab(app.TabGroup);
            app.CollageTab.Title = 'Collage ';
            app.TabPunto1 = uitabgroup(app.CollageTab);
            app.CreatecollageTab = uitab(app.TabPunto1);
            app.CreatecollageTab.Title = 'Create collage';
            app.collageAxis = uiaxes(app.CreatecollageTab);
            app.collageAxis.XTick = [];
            app.collageAxis.YTick = [];
            app.Label_8 = uilabel(app.CreatecollageTab);
            app.Label_8.Text = {'To create your collage you can select any amount images, the distribution of the images'; 'is randomly generated, you must create the canvas first.'};
            app.AddNewImageButton = uibutton(app.CreatecollageTab, 'push');
            app.AddNewImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewImageButtonPushed, true);
            app.AddNewImageButton.Text = 'Add New Image';
            app.UndoButton_2 = uibutton(app.CreatecollageTab, 'push');
            app.UndoButton_2.ButtonPushedFcn = createCallbackFcn(app, @UndoButton_2Pushed, true);
            app.UndoButton_2.Text = 'Undo';
            app.ExportCollageButton = uibutton(app.CreatecollageTab, 'push');
            app.ExportCollageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCollageButtonPushed, true);
            app.ExportCollageButton.Text = 'Export Collage';
            app.SizeEditFieldLabel = uilabel(app.CreatecollageTab);
            app.SizeEditFieldLabel.Text = 'Size = ';
            app.SizeEditField = uieditfield(app.CreatecollageTab, 'numeric');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 500;
            app.elStr23EditField_2 = uieditfield(app.CreatecollageTab, 'numeric');
            app.elStr23EditField_2.ValueChangedFcn = createCallbackFcn(app, @elStr23EditField_2ValueChanged, true);
            app.elStr23EditField_2.Value = 500;
            app.NewCanvasButton = uibutton(app.CreatecollageTab, 'push');
            app.NewCanvasButton.ButtonPushedFcn = createCallbackFcn(app, @NewCanvasButtonPushed, true);
            app.NewCanvasButton.Text = 'New Canvas';
            app.ResizeCanvasButton = uibutton(app.CreatecollageTab, 'push');
            app.ResizeCanvasButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCanvasButtonPushed, true);
            app.ResizeCanvasButton.Text = 'Resize Canvas';
            app.AddCurrentProcessedImageButton = uibutton(app.CreatecollageTab, 'push');
            app.AddCurrentProcessedImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddCurrentProcessedImageButtonPushed, true);
            app.AddCurrentProcessedImageButton.Text = 'Add Current Processed Image';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.rotacionEspejoAxis = uiaxes(app.RotationTab);
            app.rotacionEspejoAxis.XTick = [];
            app.rotacionEspejoAxis.YTick = [];
            app.RotateimageButton = uibutton(app.RotationTab, 'push');
            app.RotateimageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateimageButtonPushed, true);
            app.RotateimageButton.Text = 'Rotate image';
            app.MirrorButton = uibutton(app.RotationTab, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Text = 'Mirror';
            app.RotationangleLabel = uilabel(app.RotationTab);
            app.RotationangleLabel.Text = 'Rotation angle';
            app.anguloEditField = uieditfield(app.RotationTab, 'numeric');
            app.anguloEditField.ValueChangedFcn = createCallbackFcn(app, @anguloEditFieldValueChanged, true);
            app.anguloEditField.Value = 45;
            app.Label_7 = uilabel(app.RotationTab);
            app.Label_7.Text = 'A rotation is a transformation where a figure is rotated around a fixed point to create an image.';
            app.Insertmultiplesof45degreesLabel = uilabel(app.RotationTab);
            app.Insertmultiplesof45degreesLabel.Text = 'Insert multiples of 45 degrees';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.filtrosAxes = uiaxes(app.FiltersTab);
            title(app.filtrosAxes, 'Processed image')
            app.filtrosAxes.XTick = [];
            app.filtrosAxes.YTick = [];
            app.TabGroup3 = uitabgroup(app.FiltersTab);
            app.SmoothingfiltersTab = uitab(app.TabGroup3);
            app.SmoothingfiltersTab.Title = 'Smoothing filters';
            app.GaussianFilterButton = uibutton(app.SmoothingfiltersTab, 'push');
            app.GaussianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterButtonPushed, true);
            app.GaussianFilterButton.Text = 'Gaussian Filter';
            app.BoxtypeLabel = uilabel(app.SmoothingfiltersTab);
            app.BoxtypeLabel.Text = {'Smoothing filters are intended to reduce '; 'noise and/or effects that may appear in an '; 'image as a result of the capture, digitization,'; 'and transmission process.'};
            app.LaplacianButton_2 = uibutton(app.SmoothingfiltersTab, 'push');
            app.LaplacianButton_2.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButton_2Pushed, true);
            app.LaplacianButton_2.Text = '4-Laplacian';
            app.LaplacianButton = uibutton(app.SmoothingfiltersTab, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = '8-Laplacian';
            app.Label_9 = uilabel(app.SmoothingfiltersTab);
            app.Label_9.Text = {'The Gaussian filter preserves edges, '; 'reduces noise by smoothing the image but '; 'the image is not sharp.'};
            app.Label_10 = uilabel(app.SmoothingfiltersTab);
            app.Label_10.Text = {'The Laplacian filter is recommended for'; ' enhancing linear features in urban '; 'environments.'};
            app.StatisticalorderTab = uitab(app.TabGroup3);
            app.StatisticalorderTab.Title = 'Statistical order';
            app.ImageaverageFilterButton = uibutton(app.StatisticalorderTab, 'push');
            app.ImageaverageFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImageaverageFilterButtonPushed, true);
            app.ImageaverageFilterButton.Text = 'Image average Filter';
            app.ImagefashionfilterButton = uibutton(app.StatisticalorderTab, 'push');
            app.ImagefashionfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImagefashionfilterButtonPushed, true);
            app.ImagefashionfilterButton.Text = 'Image fashion filter';
            app.NOrderfilterEditFieldLabel = uilabel(app.StatisticalorderTab);
            app.NOrderfilterEditFieldLabel.Text = 'N Order filter:';
            app.NOrderfilterEditField = uieditfield(app.StatisticalorderTab, 'numeric');
            app.NOrderfilterEditField.ValueChangedFcn = createCallbackFcn(app, @NOrderfilterEditFieldValueChanged, true);
            app.NOrderfilterEditField.Value = 1;
            app.FilterButton = uibutton(app.StatisticalorderTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.Label_11 = uilabel(app.StatisticalorderTab);
            app.Label_11.Text = {'They are useful when the image is supposed '; 'to have a lot of noise and you want to remove '; 'it. They can also be used to highlight '; 'information corresponding to a certain scale '; '(size of the filter matrix).'};
            app.ImagemedianfilterButton = uibutton(app.StatisticalorderTab, 'push');
            app.ImagemedianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImagemedianfilterButtonPushed, true);
            app.ImagemedianfilterButton.Text = 'Image median filter';
            app.ImagemaxfilterButton = uibutton(app.StatisticalorderTab, 'push');
            app.ImagemaxfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImagemaxfilterButtonPushed, true);
            app.ImagemaxfilterButton.Text = 'Image max filter';
            app.ImageminfilterButton = uibutton(app.StatisticalorderTab, 'push');
            app.ImageminfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImageminfilterButtonPushed, true);
            app.ImageminfilterButton.Text = 'Image min filter';
            app.EdgedetectionTab = uitab(app.TabGroup3);
            app.EdgedetectionTab.Title = 'Edge detection';
            app.SobelButton = uibutton(app.EdgedetectionTab, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uibutton(app.EdgedetectionTab, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uibutton(app.EdgedetectionTab, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.Label_12 = uilabel(app.EdgedetectionTab);
            app.Label_12.Text = {'They look for the edges between different '; 'colors and thus can detect the outlines of '; 'objects. They are used to make selections'; ' and for other artistic proposals. Most of '; 'them are based on gradient calculation '; 'methods and generate thick lines.'};
            app.IthastheabilitytoshowedgepointsbutnottheirorientationLabel = uilabel(app.EdgedetectionTab);
            app.IthastheabilitytoshowedgepointsbutnottheirorientationLabel.Text = {'It has the ability to show edge points but'; 'not their orientation.'};
            app.GetsthegradientfortheedgesAlthoughthemaskisdifferentLabel = uilabel(app.EdgedetectionTab);
            app.GetsthegradientfortheedgesAlthoughthemaskisdifferentLabel.Text = {'Gets the gradient for the edges. Although'; ' the mask is different'};
            app.GetsthegradientfortheedgesAlthoughthemaskisdifferentLabel_2 = uilabel(app.EdgedetectionTab);
            app.GetsthegradientfortheedgesAlthoughthemaskisdifferentLabel_2.Text = {'Detects the horizontal and vertical edges '; 'separately on a grayscale image.'};
            app.MorphologicaloperationsTab = uitab(app.TabGroup);
            app.MorphologicaloperationsTab.Title = 'Morphological operations';
            app.MorfoAxes = uiaxes(app.MorphologicaloperationsTab);
            title(app.MorfoAxes, 'Processed image')
            app.MorfoAxes.XTick = [];
            app.MorfoAxes.YTick = [];
            app.Dilatacion = uibutton(app.MorphologicaloperationsTab, 'push');
            app.Dilatacion.ButtonPushedFcn = createCallbackFcn(app, @DilatacionButtonPushed, true);
            app.Dilatacion.Text = 'Apply dilation';
            app.Erosion = uibutton(app.MorphologicaloperationsTab, 'push');
            app.Erosion.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.Erosion.Text = 'Apply erosion';
            app.Label = uilabel(app.MorphologicaloperationsTab);
            app.Label.Text = '';
            app.elStr11EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.elStr13EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.elStr22EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.elStr22EditField.Value = 1;
            app.elStr23EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.Label_3 = uilabel(app.MorphologicaloperationsTab);
            app.Label_3.Text = '';
            app.elStr21EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.elStr32EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.elStr33EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.Label_4 = uilabel(app.MorphologicaloperationsTab);
            app.Label_4.Text = '';
            app.elStr31EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.elStr12EditField = uieditfield(app.MorphologicaloperationsTab, 'numeric');
            app.StructuringelementLabel = uilabel(app.MorphologicaloperationsTab);
            app.StructuringelementLabel.Text = 'Structuring element';
            app.DilationaddspixelstotheboundariesofobjectsinanimageLabel = uilabel(app.MorphologicaloperationsTab);
            app.DilationaddspixelstotheboundariesofobjectsinanimageLabel.Text = {'*Dilation adds pixels to the boundaries of objects '; 'in an image'};
            app.ErosionremovepixelsfromtheboundariesofobjectsLabel = uilabel(app.MorphologicaloperationsTab);
            app.ErosionremovepixelsfromtheboundariesofobjectsLabel.Text = {'*Erosion remove pixels from the boundaries'; ' of objects'};
            app.SegmentationforNregionsTab = uitab(app.TabGroup);
            app.SegmentationforNregionsTab.Title = 'Segmentation for N regions';
            app.segAxes = uiaxes(app.SegmentationforNregionsTab);
            title(app.segAxes, 'Segmented Image')
            app.segAxes.XTick = [];
            app.segAxes.YTick = [];
            app.SegmentationimsegkmeansButton = uibutton(app.SegmentationforNregionsTab, 'push');
            app.SegmentationimsegkmeansButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationimsegkmeansButtonPushed, true);
            app.SegmentationimsegkmeansButton.Text = 'Segmentation (imsegkmeans)';
            app.NRegionssegmentationSLICButton = uibutton(app.SegmentationforNregionsTab, 'push');
            app.NRegionssegmentationSLICButton.ButtonPushedFcn = createCallbackFcn(app, @NRegionssegmentationSLICButtonPushed, true);
            app.NRegionssegmentationSLICButton.Text = 'N Regions segmentation (SLIC)';
            app.NRegionsEditFieldLabel = uilabel(app.SegmentationforNregionsTab);
            app.NRegionsEditFieldLabel.Text = 'N Regions = ';
            app.NRegionsEditField = uieditfield(app.SegmentationforNregionsTab, 'numeric');
            app.NRegionsEditField.Value = 1;
            app.Label_13 = uilabel(app.SegmentationforNregionsTab);
            app.Label_13.Text = 'Image segmentation is a commonly used technique in digital image processing and analysis to partition an image into multiple parts or regions, often based on the characteristics of the pixels in the image. ';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.ExportAxes = uiaxes(app.SaveTab);
            title(app.ExportAxes, 'Processed image')
            app.ExportAxes.XTick = [];
            app.ExportAxes.YTick = [];
            app.ExportprocessedimageButton = uibutton(app.SaveTab, 'push');
            app.ExportprocessedimageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportprocessedimageButtonPushed, true);
            app.ExportprocessedimageButton.Text = 'Export processed image';
            app.ExportoriginalimageButton = uibutton(app.SaveTab, 'push');
            app.ExportoriginalimageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportoriginalimageButtonPushed, true);
            app.ExportoriginalimageButton.Text = 'Export original image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoPID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end